classdef hf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        OpenfileEditFieldLabel  matlab.ui.control.Label
        OpenfileEditField       matlab.ui.control.EditField
        OpenButton              matlab.ui.control.Button
        BrowseButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        Pax % Polar axes
        file % the name of opened file
        path % path to file
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            plotline = polarplot(app.Pax, [10 5], [10 15], 'xb');
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [100, 100, 230, 230];
            
            % Plot the polar function
            %updatePlot(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            %text=fopen(app.OpenfileEditField.Value);
            %app.OpenfileEditField.Value=fgets(text);
            if app.OpenfileEditField.Value ~= ""
                dialog_box = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Indeterminate','on');
                dialog_box.Message = 'Loading NMEA data...';
                data = importfile(app.OpenfileEditField.Value, "$GPGSV");
                sat1 = [data{1, 6} data{1, 7}]
                sat2 = [data{1, 10} data{1, 11}]
                polarplot(app.Pax, sat1, sat2, 'xb');
                pause(1);
                close(dialog_box);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            com.mathworks.mwswing.MJFileChooserPerPlatform.setUseSwingDialog(1);
            [app.file, app.path] = uigetfile('*.txt');
            if isequal(app.file, 0)

            else
                app.OpenfileEditField.Value=strcat(app.path, app.file);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.OpenfileEditFieldLabel = uilabel(app.Panel);
            app.OpenfileEditFieldLabel.Text = 'Open file:';
            app.OpenfileEditField = uieditfield(app.Panel, 'text');
            app.OpenfileEditField.Value = 'C:\';
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
        end
    end

    methods (Access = public)

        % Construct app
        function app = hf

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end